classdef visual_modos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        ComprimentoSliderLabel  matlab.ui.control.Label
        ComprimentoSlider       matlab.ui.control.Slider
        UIAxes2                 matlab.ui.control.UIAxes
        AnaliseButton           matlab.ui.control.Button
    end


    properties (Access = private)
        perfil = 'E' % Indicacao do perfil para escolha das propriedades
        coord % Coordenadas dos nos
        elem % Conectividade dos nos
        mat % propriedades do material
        CC = 'A-A';% Condicoes de contorno
        onda = [1]; % Numero e quantidade de meias onda
        L = logspace(1,4,100); % Compriemntos da barra
        modo_carga_critico % Comprimento , modos e cargas criticas
        plot_ass % Dados para Curva de assinatura
        no1 % Vetor do primeiro no de cada elemento
        no2 % Vetor do primeiro no de cada elemento
        b % Vetor com a largura de cada elemento
        Lindex = 1; %Indice do comprimento de flambagem
        select_modo = 1; % Modo Selecionado, de acordo com indice no vetor onda
        Xpontos = 8; % Quantidade de pontos internos do elemento no eixo x, para interpolar os deslocamentos nodais no dominio x.
        zbar = 0.4; % Parametro de escolha da secao no eixo longitudinal do perfil: 0 representa o comeco e 1 o final da coluna.
        Escala=10; % Fator de Escala para aumentar ou diminuir o modo de flambagem
        h1 % Plot do perfil inderformado
        h2 % Plot do perfil deformado 2D
        m1 % Plot da indicacao do comprimento na curva de assinatura
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                     %% DADOS INICIAIS
% Etapa inicial de indentificação do perfil estrutural a ser analisado

% tic % Começo de contagem do tempo %COMECO DE CONTAGEM DO TEMPO DA ANALISE DE FAIXAS
% FINITAS

%   ENTRADA:
%   perfil: Indicação do perfil a ser escolhido
%          (A) PERFIL U 100 X 50 X 1.00 (sem raio de concordância)
%          (B) PERFIL Ue 100 X 50 X 15 X 1.00 (sem raio de concordância)
%          (C) PERFIL Z 100 X 40 X 1.00 (sem raio de concordância)
%          (D) PERFIL Ze 100 X 40 X 20 X 1.00 (sem raio de concordância, e teta 45)
%          (E) PERFIL None 100 X 50 X 40 X 30 X 2.00 (sem raio de concordância)
%          (F) PERFIL Ue em unidade N e mm
%          (G) PERFIL Placa em unidade kN e mm
%          (H) PERFIL Ue em unidade kN e mm, com interacao DG

% rodar_CUFSM='no'; % Flag para rodar a funcao strip.m do CUFSM


[app.coord,app.elem,app.mat,~,~,~,~] = INPUT(app.perfil); % Dados de Entrada (Perfil escolhido)

app.h1=cell(1,length(app.elem(:,1)));
app.h2=cell(1,length(app.elem(:,1)));
app.m1=[];


        end

        % Value changed function: ComprimentoSlider
        function ComprimentoSliderValueChanged(app, event)
            value = app.ComprimentoSlider.Value;
            app.ComprimentoSlider.Value = round(value,0);
%             app.Lindex = round(app.ComprimentoSlider.Value,0);
            app.Lindex = app.ComprimentoSlider.Value;
            for ii=1:length(app.elem(:,1))
                delete(app.h2{ii})
            end
            delete(app.m1)
 
% Plot do Perfil e do Modo

% propplot(app.coord,app.elem,xg,yg,teta) % Plot da seção do perfil

% axis('equal')
% axis('off')
% props1 = {'Color',[0.6350 0.0780 0.1840],'LineStyle','-','LineWidth',app.elem(:,4),...
%          'Marker','o','MarkerEdge','b','MarkerSize',2,...
%          'MarkerFaceColor',[0.3010 0.7450 0.9330]};
     
props2 = {'Color','r','LineStyle','-','LineWidth',app.elem(:,4),...
         'Marker','none'};
% cla;


NNOS = size(app.coord,1); % Número de nós
NONDA = length(app.onda); % Numero total de termos longitudinais


xbar = linspace(0,1,app.Xpontos+1); % Coordenada parametrica em x dos pontos de interpolacao.

    switch app.CC
        case 'A-A'
            Zp = sin(app.zbar*app.onda*pi); % Funcao de forma longitudinal para simplesmente apoiado
            DZpDy_lambdap = cos(app.zbar*app.onda*pi); % Derivada da Funcao de forma longitudinal dividido pelo lambda_p para simplesmente apoiado
        case 'E-E'
            Zp = sin(app.zbar*app.onda*pi).*sin(app.zbar*pi); % Funcao de forma longitudinal para bi-engastado
            DZpDy_lambdap = sin(app.zbar*pi).*cos(app.zbar*app.onda*pi) + (1./app.onda).*sin(app.zbar*app.onda*pi).*cos(app.zbar*pi); % Derivada da Funcao de forma longitudinal dividido pelo lambda_p para bi-engastado
    end


[XBAR,ZP]=ndgrid(xbar,Zp); % Organizando os vetores para caluclo vetorizado
[~,DZPDy_LambdaP]=ndgrid(xbar,DZpDy_lambdap); % Organizando os vetores para caluclo vetorizado

for ii=1:length(app.elem(:,1))
    xno1ii=app.coord(app.no1(ii),2); %Coordenada em x do no1 da ii-esima faixa
    yno1ii=app.coord(app.no1(ii),3); %Coordenada em y do no1 da ii-esima faixa
    xno2ii=app.coord(app.no2(ii),2); %Coordenada em x do no2 da ii-esima faixa
    yno2ii=app.coord(app.no2(ii),3); %Coordenada em y do no2 da ii-esima faixa
%     app.h1{ii}=line(app.UIAxes2,[xno1ii xno2ii],[yno1ii yno2ii],props1{1:5},props1{6}(ii),props1{7:end}); % Plot do perfil inicial
    
    p1 = 2*NNOS*(2*((1:NONDA)-1)); % Acessa os graus de liberdade u e v do modo pp.
    p2 = 2*NNOS*(2*((1:NONDA)-1) + 1); % Acessa os graus de liberdade w e theta do modo pp.
    
    no11 = 2*app.no1(ii) - 1; % Acessa os graus de liberdade u1 e w1
    no12 = 2*app.no1(ii);     % Acessa os graus de liberdade v1 e theta1
    no21 = 2*app.no2(ii) - 1; % Acessa os graus de liberdade u2 e w2
    no22 = 2*app.no2(ii);     % Acessa os graus de liberdade v2 e theta2

    iu1_p = p1 + no11; % Indice que Acessa o grau de liberdade u1 para o modo pp
    iv1_p = p1 + no12; % Indice que Acessa o grau de liberdade v1 para o modo pp
    iu2_p = p1 + no21; % Indice que Acessa o grau de liberdade u2 para o modo pp
    iv2_p = p1 + no22; % Indice que Acessa o grau de liberdade v2 para o modo pp
    iw1_p = p2 + no11; % Indice que Acessa o grau de liberdade w1 para o modo pp
    iO1_p = p2 + no12; % Indice que Acessa o grau de liberdade theta1 para o modo pp
    iw2_p = p2 + no21; % Indice que Acessa o grau de liberdade w2 para o modo pp
    iO2_p = p2 + no22; % Indice que Acessa o grau de liberdade theta2 para o modo pp
    
  
    modo=app.modo_carga_critico{app.Lindex}{2}(:,app.select_modo)';
    Desl_Nodal_global = [modo(iu1_p);modo(iv1_p);modo(iu2_p);modo(iv2_p);modo(iw1_p);modo(iO1_p);modo(iw2_p);modo(iO2_p)];  % dglobal=(u1 v1 u2 v2 w1 01 w2 02)'
    % Desl_Nodal_globall = (u1 v1 u2 v2 w1 01 w2 02)' p=1
    %                      (u1 v1 u2 v2 w1 01 w2 02)' p=2
    %                      (u1 v1 u2 v2 w1 01 w2 02)' p=3 ...
    %                      (u1 v1 u2 v2 w1 01 w2 02)' p=NONDA
    
    phy=atan2(-(yno2ii-yno1ii),(xno2ii-xno1ii));
    [gamma1,~] = Rotacao8(phy);
    Desl_Nodal_local = gamma1*Desl_Nodal_global;
    
    u1 = Desl_Nodal_local(1,:); v1 = Desl_Nodal_local(2,:); u2 = Desl_Nodal_local(3,:);  v2 = Desl_Nodal_local(4,:);
    w1 = Desl_Nodal_local(5,:); O1 = Desl_Nodal_local(6,:); w2 = Desl_Nodal_local(7,:);  O2 = Desl_Nodal_local(8,:);
    

    [~,U1]=ndgrid(xbar,u1); [~,V1]=ndgrid(xbar,v1); [~,U2]=ndgrid(xbar,u2); [~,V2]=ndgrid(xbar,v2);
    [~,W1]=ndgrid(xbar,w1); [~,OO1]=ndgrid(xbar,O1); [~,W2]=ndgrid(xbar,w2); [~,OO2]=ndgrid(xbar,O2);

    u = sum((U1.*(1-XBAR).*ZP + U2.*XBAR.*ZP),2);
    v = sum((V1.*(1-XBAR).*DZPDy_LambdaP + V2.*XBAR.*DZPDy_LambdaP),2);
    w = sum(ZP.*(W1.*(1 - 3*XBAR.^2 + 2*XBAR.^3) + OO1.*XBAR*app.b(ii).*(1 - 2*XBAR + XBAR.^2) + W2.*(3*XBAR.^2 - 2*XBAR.^3) + OO2.*XBAR*app.b(ii).*(XBAR.^2 - XBAR)),2);

    Desl_Inter_local=[u';v';w'];
    
    [~,gamma2] = Rotacao8(phy);
        
    Desl_Inter_global = gamma2\Desl_Inter_local;
    
    xii = xbar.*(xno2ii-xno1ii) + xno1ii;
    yii = xbar.*(yno2ii-yno1ii) + yno1ii;
    zii = zeros(size(xbar));
    
    xii_desl = xii + app.Escala*Desl_Inter_global(1,:); % ATENCAO: O eixo x segue o deslocamento de u 
    yii_desl = yii + app.Escala*Desl_Inter_global(3,:); % ATENCAO: O eixo y segue o deslocamento de w 
    zii_desl = zii + app.Escala*Desl_Inter_global(2,:); % ATENCAO: O eixo z segue o deslocamento de v
    % Obs: Recomenda-se que altere a variavel y por z e vice versa para que
    % os deslocamentos sigam a ordem u,v,w em relacao a x,y,z. (nesta
    % formulacao os eixo x,y,z seguem os deslocamentos u,w,v)
    app.h2{ii}=line(app.UIAxes2,xii_desl,yii_desl,zii_desl,props2{1:5},props2{6}(ii),props2{7:end}); % Plot do perfil inicial
    
end

% Plot do ponto de vizualizacao
app.m1=line(app.UIAxes,app.L(app.Lindex),app.plot_ass(app.select_modo,app.Lindex),...
    'Color','b','LineStyle','-','LineWidth',1,'Marker','o','MarkerSize',3);

    
        end

        % Button pushed function: AnaliseButton
        function AnaliseButtonPushed(app, event)
            
            app.ComprimentoSlider.Limits = [1,length(app.L)];
            if length(app.L) < 50
                app.ComprimentoSlider.MinorTicks = 1:1:length(app.L);
            else
                app.ComprimentoSlider.MinorTicks = [];
            end
            app.ComprimentoSlider.Value = round(app.Lindex,0);

   
for ii=1:length(app.elem(:,1))
    delete(app.h1{ii})
    delete(app.h2{ii})
end
delete(app.m1)

% COMPRIMENTOS DA BARRA (Eixo z)
% L=(10:100:10000);


%% ANÁLISE EM FAIXAS FINITAS (PRÉ-PROCESSAMENTO DOS DADOS e FORMAÇÃO DAS MATRISES DE RIGIDEZ E SOLVER)
% Formação das matrizes de rigidez elastica e geometrica, e calculo das
% cargas críticas e modos críticos com um solver para problema de autovalor
% e Organização dos dados de entrada para processar a análise
warning off
[app.modo_carga_critico,app.plot_ass] = faixas_finitas(app.elem,app.coord,app.mat,app.CC,app.onda,app.L);

%% PÓS-PROCESSAMENTO
% Criação de gráficos que representem melhor compreendimento dos
% resultados, atravez da curva de assinatura.
[app.no1,app.no2,app.b,~,~,~,~,A,xg,yg,teta] = pre_FF(app.elem,app.coord);

% PLOT DO PERFIL, CURVA DE ASSINATURA E MODO


%% (undef,node,app.elem,mode,axesnum,scalem,springs,m_a,app.CC,SurfPos)


% app.Lindex = 150; %Indice do comprimento de flambagem
% app.select_modo = 1; % Modo Selecionado, de acordo com indice no vetor onda
% app.Xpontos = 8; %Quantidade de pontos internos do elemento no eixo x, para interpolar os deslocamentos nodais no dominio x.
% app.zbar = 0.4; %Parametro de escolha da secao no eixo longitudinal do perfil: 0 representa o comeco e 1 o final da coluna.
% app.Escala=10; %Fator de Escala para aumentar ou diminuir o modo de flambagem


% Plot da curva de assinatura

MinLocal=islocalmin(app.plot_ass(app.select_modo,:));
semilogx(app.UIAxes,app.L,app.plot_ass(app.select_modo,:),app.L(MinLocal),app.plot_ass(app.select_modo,MinLocal),'*r') % Plot da curva de assinatura

% title({'Curva de Assinatura';'Perfil Formado a Frio - faixa.m'});
% xlabel('Comprimento')
% ylabel('Carga Critica')


% Plot do Perfil e do Modo

% propplot(app.coord,app.elem,xg,yg,teta) % Plot da seção do perfil

% axis('equal')
% axis('off')
props1 = {'Color',[0.6350 0.0780 0.1840],'LineStyle','-','LineWidth',app.elem(:,4),...
         'Marker','o','MarkerEdge','b','MarkerSize',2,...
         'MarkerFaceColor',[0.3010 0.7450 0.9330]};
     
props2 = {'Color','r','LineStyle','-','LineWidth',app.elem(:,4),...
         'Marker','none'};
% cla;


NNOS = size(app.coord,1); % Número de nós
NONDA = length(app.onda); % Numero total de termos longitudinais


xbar = linspace(0,1,app.Xpontos+1); % Coordenada parametrica em x dos pontos de interpolacao.

    switch app.CC
        case 'A-A'
            Zp = sin(app.zbar*app.onda*pi); % Funcao de forma longitudinal para simplesmente apoiado
            DZpDy_lambdap = cos(app.zbar*app.onda*pi); % Derivada da Funcao de forma longitudinal dividido pelo lambda_p para simplesmente apoiado
        case 'E-E'
            Zp = sin(app.zbar*app.onda*pi).*sin(app.zbar*pi); % Funcao de forma longitudinal para bi-engastado
            DZpDy_lambdap = sin(app.zbar*pi).*cos(app.zbar*app.onda*pi) + (1./app.onda).*sin(app.zbar*app.onda*pi).*cos(app.zbar*pi); % Derivada da Funcao de forma longitudinal dividido pelo lambda_p para bi-engastado
    end


[XBAR,ZP]=ndgrid(xbar,Zp); % Organizando os vetores para caluclo vetorizado
[~,DZPDy_LambdaP]=ndgrid(xbar,DZpDy_lambdap); % Organizando os vetores para caluclo vetorizado

for ii=1:length(app.elem(:,1))
    xno1ii=app.coord(app.no1(ii),2); %Coordenada em x do no1 da ii-esima faixa
    yno1ii=app.coord(app.no1(ii),3); %Coordenada em y do no1 da ii-esima faixa
    xno2ii=app.coord(app.no2(ii),2); %Coordenada em x do no2 da ii-esima faixa
    yno2ii=app.coord(app.no2(ii),3); %Coordenada em y do no2 da ii-esima faixa
    app.h1{ii}=line(app.UIAxes2,[xno1ii xno2ii],[yno1ii yno2ii],props1{1:5},props1{6}(ii),props1{7:end}); % Plot do perfil inicial
    
    p1 = 2*NNOS*(2*((1:NONDA)-1)); % Acessa os graus de liberdade u e v do modo pp.
    p2 = 2*NNOS*(2*((1:NONDA)-1) + 1); % Acessa os graus de liberdade w e theta do modo pp.
    
    no11 = 2*app.no1(ii) - 1; % Acessa os graus de liberdade u1 e w1
    no12 = 2*app.no1(ii);     % Acessa os graus de liberdade v1 e theta1
    no21 = 2*app.no2(ii) - 1; % Acessa os graus de liberdade u2 e w2
    no22 = 2*app.no2(ii);     % Acessa os graus de liberdade v2 e theta2

    iu1_p = p1 + no11; % Indice que Acessa o grau de liberdade u1 para o modo pp
    iv1_p = p1 + no12; % Indice que Acessa o grau de liberdade v1 para o modo pp
    iu2_p = p1 + no21; % Indice que Acessa o grau de liberdade u2 para o modo pp
    iv2_p = p1 + no22; % Indice que Acessa o grau de liberdade v2 para o modo pp
    iw1_p = p2 + no11; % Indice que Acessa o grau de liberdade w1 para o modo pp
    iO1_p = p2 + no12; % Indice que Acessa o grau de liberdade theta1 para o modo pp
    iw2_p = p2 + no21; % Indice que Acessa o grau de liberdade w2 para o modo pp
    iO2_p = p2 + no22; % Indice que Acessa o grau de liberdade theta2 para o modo pp
    
    modo=app.modo_carga_critico{app.Lindex}{2}(:,app.select_modo)';
    Desl_Nodal_global = [modo(iu1_p);modo(iv1_p);modo(iu2_p);modo(iv2_p);modo(iw1_p);modo(iO1_p);modo(iw2_p);modo(iO2_p)];  % dglobal=(u1 v1 u2 v2 w1 01 w2 02)'
    % Desl_Nodal_globall = (u1 v1 u2 v2 w1 01 w2 02)' p=1
    %                      (u1 v1 u2 v2 w1 01 w2 02)' p=2
    %                      (u1 v1 u2 v2 w1 01 w2 02)' p=3 ...
    %                      (u1 v1 u2 v2 w1 01 w2 02)' p=NONDA
    
    phy=atan2(-(yno2ii-yno1ii),(xno2ii-xno1ii));
    [gamma1,~] = Rotacao8(phy);
    Desl_Nodal_local = gamma1*Desl_Nodal_global;
    
    u1 = Desl_Nodal_local(1,:); v1 = Desl_Nodal_local(2,:); u2 = Desl_Nodal_local(3,:);  v2 = Desl_Nodal_local(4,:);
    w1 = Desl_Nodal_local(5,:); O1 = Desl_Nodal_local(6,:); w2 = Desl_Nodal_local(7,:);  O2 = Desl_Nodal_local(8,:);
    

    [~,U1]=ndgrid(xbar,u1); [~,V1]=ndgrid(xbar,v1); [~,U2]=ndgrid(xbar,u2); [~,V2]=ndgrid(xbar,v2);
    [~,W1]=ndgrid(xbar,w1); [~,OO1]=ndgrid(xbar,O1); [~,W2]=ndgrid(xbar,w2); [~,OO2]=ndgrid(xbar,O2);

    u = sum((U1.*(1-XBAR).*ZP + U2.*XBAR.*ZP),2);
    v = sum((V1.*(1-XBAR).*DZPDy_LambdaP + V2.*XBAR.*DZPDy_LambdaP),2);
    w = sum(ZP.*(W1.*(1 - 3*XBAR.^2 + 2*XBAR.^3) + OO1.*XBAR*app.b(ii).*(1 - 2*XBAR + XBAR.^2) + W2.*(3*XBAR.^2 - 2*XBAR.^3) + OO2.*XBAR*app.b(ii).*(XBAR.^2 - XBAR)),2);

    Desl_Inter_local=[u';v';w'];
    
    [~,gamma2] = Rotacao8(phy);
        
    Desl_Inter_global = gamma2\Desl_Inter_local;
    
    xii = xbar.*(xno2ii-xno1ii) + xno1ii;
    yii = xbar.*(yno2ii-yno1ii) + yno1ii;
    zii = zeros(size(xbar));
    
    xii_desl = xii + app.Escala*Desl_Inter_global(1,:); % ATENCAO: O eixo x segue o deslocamento de u 
    yii_desl = yii + app.Escala*Desl_Inter_global(3,:); % ATENCAO: O eixo y segue o deslocamento de w 
    zii_desl = zii + app.Escala*Desl_Inter_global(2,:); % ATENCAO: O eixo z segue o deslocamento de v
    % Obs: Recomenda-se que altere a variavel y por z e vice versa para que
    % os deslocamentos sigam a ordem u,v,w em relacao a x,y,z. (nesta
    % formulacao os eixo x,y,z seguem os deslocamentos u,w,v)
    app.h2{ii}=line(app.UIAxes2,xii_desl,yii_desl,zii_desl,props2{1:5},props2{6}(ii),props2{7:end}); % Plot do perfil inicial
    
end


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Curva de Assinatura')
            xlabel(app.UIAxes, 'Comprimento')
            ylabel(app.UIAxes, 'Carga Critica')
            app.UIAxes.XScale = 'log';
            app.ComprimentoSliderLabel = uilabel(app.UIFigure);
            app.ComprimentoSliderLabel.Text = 'Comprimento';
            app.ComprimentoSlider = uislider(app.UIFigure);
            app.ComprimentoSlider.MajorTicks = [];
            app.ComprimentoSlider.MajorTickLabels = {};
            app.ComprimentoSlider.ValueChangedFcn = createCallbackFcn(app, @ComprimentoSliderValueChanged, true);
            app.ComprimentoSlider.MinorTicks = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.AnaliseButton = uibutton(app.UIFigure, 'push');
            app.AnaliseButton.ButtonPushedFcn = createCallbackFcn(app, @AnaliseButtonPushed, true);
            app.AnaliseButton.Text = 'Analise';
        end
    end

    methods (Access = public)

        % Construct app
        function app = visual_modos

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end